classdef RespGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ChooseDataFilesListBoxLabel     matlab.ui.control.Label
        ChooseDataFilesListBox          matlab.ui.control.ListBox
        ChooseFilepathButton            matlab.ui.control.Button
        DataSampleRateEditFieldLabel    matlab.ui.control.Label
        DataSampleRateEditField         matlab.ui.control.NumericEditField
        SamplingRateUnitsDropDownLabel  matlab.ui.control.Label
        SamplingRateUnitsDropDown       matlab.ui.control.DropDown
        ChosendatafilemustbeofLabel     matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        RespirationPeakDetectionandQualityIndexingPanel  matlab.ui.container.Panel
        RespirationPatternVariabilityPanel  matlab.ui.container.Panel
        AdaptiveThresholdingWindowLengthsEditField_2Label  matlab.ui.control.Label
        AdaptiveThresholdingWindowLengthsEditField_2  matlab.ui.control.NumericEditField
        AdaptiveThresholdingWindowShiftsEditField_2Label  matlab.ui.control.Label
        AdaptiveThresholdingWindowShiftsEditField_2  matlab.ui.control.NumericEditField
        RespirationQualityAssessmentPanel  matlab.ui.container.Panel
        RQIWindowLengthsEditFieldLabel  matlab.ui.control.Label
        RQIWindowLengthsEditField       matlab.ui.control.NumericEditField
        RQIThresholdEditFieldLabel      matlab.ui.control.Label
        RQIThresholdEditField           matlab.ui.control.NumericEditField
        RQIWindowShiftEditFieldLabel    matlab.ui.control.Label
        RQIWindowShiftEditField         matlab.ui.control.NumericEditField
        ECGProcessingPanel              matlab.ui.container.Panel
        ECGRPeakDetectionPanel          matlab.ui.container.Panel
        sqi_jw_threshEditField_2Label   matlab.ui.control.Label
        sqi_jw_threshEditField_2        matlab.ui.control.NumericEditField
        sqi_js_threshEditField_2Label   matlab.ui.control.Label
        sqi_js_threshEditField_2        matlab.ui.control.NumericEditField
        RemoveArrhythmCheckBox          matlab.ui.control.CheckBox
        ECGSignalQualityAssessmentPanel  matlab.ui.container.Panel
        ArrhythmiaOutlierRemovalMADsLabel  matlab.ui.control.Label
        ArrhythmiaOutlierRemovalMADsEditField  matlab.ui.control.NumericEditField
        UpperOutlierThresholdstddevEditFieldLabel  matlab.ui.control.Label
        UpperOutlierThresholdstddevEditField  matlab.ui.control.NumericEditField
        LowerOutlierThresholdstddevEditFieldLabel  matlab.ui.control.Label
        LowerOutlierThresholdstddevEditField  matlab.ui.control.NumericEditField
        RunProcessingButton             matlab.ui.control.Button
        IwanttoeditprocessingparametersCheckBox  matlab.ui.control.CheckBox
        RespirationPeakDetectionPanel   matlab.ui.container.Panel
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        vera_zamoscikButton             matlab.ui.control.RadioButton
        count_advButton                 matlab.ui.control.RadioButton
        MinPlausibleIBIsEditFieldLabel  matlab.ui.control.Label
        MinPlausibleIBIsEditField       matlab.ui.control.NumericEditField
        MaxPlausibleIBIsEditFieldLabel  matlab.ui.control.Label
        MaxPlausibleIBIsEditField       matlab.ui.control.NumericEditField
        AdaptiveThresholdingWindowLengthsEditFieldLabel  matlab.ui.control.Label
        AdaptiveThresholdingWindowLengthsEditField  matlab.ui.control.NumericEditField
        AdaptiveThresholdingWindowShiftsEditFieldLabel  matlab.ui.control.Label
        AdaptiveThresholdingWindowShiftsEditField  matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseFilepathButton
        function ChooseFilepath(app, event)
            selpath = uigetdir;
            mydirectory = dir([selpath '\*.mat']);
            app.ChooseDataFilesListBoxLabel.Text = selpath;
            for i=1:size(mydirectory,1)
                myfiles{i} = mydirectory(i).name;
            end
            if ~isempty(myfiles)
             app.ChooseDataFilesListBox.Items= myfiles;
            end
        end

        % Callback function
        function DataProvidedChanged(app, event)
            selectedButton = app.DataProvidedButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Only ECG') | strcmp(selectedButton.Text, 'Only Respiration')
                app.MyECGRespirationdatahavedifferentsamplingratesCheckBox.Visible=0;
            else 
                app.MyECGRespirationdatahavedifferentsamplingratesCheckBox.Visible=1;
            end
        end

        % Callback function
        function DiffSampRates(app, event)
            value = app.MyECGRespirationdatahavedifferentsamplingratesCheckBox.Value;
            if value
                app.RSPSampleRateEditField.Visible = true;
                app.ECGSampleRateEditField.Visible = true;
                app.UnitsDropDown.Visible = true;
                app.UnitsDropDown_2.Visible = true;
                app.ECGSampleRateEditFieldLabel.Text = 'ECG Sample Rate';
                app.RSPSampleRateEditFieldLabel.Text = 'RSP Sample Rate';
            else
                app.RSPSampleRateEditField.Visible = false;
                app.ECGSampleRateEditField.Visible = false;
                app.UnitsDropDown.Visible = false;
                app.UnitsDropDown_2.Visible = false;
                app.ECGSampleRateEditFieldLabel.Text = '';
                app.RSPSampleRateEditFieldLabel.Text = '';
            end
        end

        % Button pushed function: RunProcessingButton
        function Run(app, event)
            %figure out the isi here
            % for now - ignore the whole 'my ecg and resp have diff fs' bc
            % that is rough
            samplerate = app.DataSampleRateEditField.Value;
            if strcmp(app.SamplingRateUnitsDropDown.Value, 'ms')
                isi = samplerate;
            elseif strcmp(app.SamplingRateUnitsDropDown.Value, 'Hz')
                isi = 1000/samplerate;
            elseif strcmp(app.SamplingRateUnitsDropDown.Value, 'kHz')
                isi = 1/samplerate;
            elseif strcmp(app.SamplingRateUnitsDropDown.Value, 'seconds')
                isi = samplerate*1000;
            end
            
            if app.vera_zamoscikButton.Value
                PeakDetectMethod = 'vera_zamoscik';
            else
                PeakDetectMethod = 'count_adv';
            end
            dataFileLocation=app.ChooseDataFilesListBoxLabel.Text;
            dataFileName=app.ChooseDataFilesListBox.Value;
            
            gui_process(dataFileLocation, dataFileName, app.RemoveArrhythmCheckBox.Value,...
                isi,app.sqi_js_threshEditField_2.Value,app.sqi_jw_threshEditField_2.Value,...
                app.UpperOutlierThresholdstddevEditField.Value,...
                app.LowerOutlierThresholdstddevEditField.Value,app.ArrhythmiaOutlierRemovalMADsEditField.Value,...
                app.AdaptiveThresholdingWindowLengthsEditField.Value, app.AdaptiveThresholdingWindowShiftsEditField.Value,...
                app.RQIWindowLengthsEditField.Value, app.RQIWindowShiftEditField.Value,...
                app.RQIThresholdEditField.Value,app.AdaptiveThresholdingWindowLengthsEditField_2.Value,...
                app.AdaptiveThresholdingWindowShiftsEditField_2.Value, app.MaxPlausibleIBIsEditField.Value,...
                app.MinPlausibleIBIsEditField.Value,PeakDetectMethod);
        end

        % Value changed function: 
        % IwanttoeditprocessingparametersCheckBox
        function editOutlier(app, event)
            value = app.IwanttoeditprocessingparametersCheckBox.Value;
            if ~value
                app.sqi_js_threshEditField_2.Enable = false;
                app.sqi_js_threshEditField_2.Value = 0.5;
                
                app.sqi_jw_threshEditField_2.Enable = false;
                app.sqi_jw_threshEditField_2.Value = 0.7;
                
                app.RemoveArrhythmCheckBox.Enable = false;
                app.RemoveArrhythmCheckBox.Value = false;
              
                app.UpperOutlierThresholdstddevEditField.Enable = false;
                app.UpperOutlierThresholdstddevEditField.Value = 4;
                
                app.LowerOutlierThresholdstddevEditField.Enable = false;
                app.LowerOutlierThresholdstddevEditField.Value = 4;
                
                app.AdaptiveThresholdingWindowLengthsEditField_2.Enable = false;
                app.AdaptiveThresholdingWindowLengthsEditField_2.Value = 300;
                
                app.AdaptiveThresholdingWindowShiftsEditField_2.Enable=false;
                app.AdaptiveThresholdingWindowShiftsEditField_2.Value = 1;
                
                app.vera_zamoscikButton.Enable =false;
                app.vera_zamoscikButton.Value =true;
                app.count_advButton.Enable = false;
                
                app.RQIThresholdEditField.Enable=false;
                app.RQIThresholdEditField.Value =0.45;
                
                app.RQIWindowShiftEditField.Enable =false;
                app.RQIWindowShiftEditField.Value = 16;
                
                app.RQIWindowLengthsEditField.Enable = false;
                app.RQIWindowLengthsEditField.Value = 16;
                
                app.MinPlausibleIBIsEditField.Enable = false;
                app.MinPlausibleIBIsEditField.Value = 1.4;
                
                app.MaxPlausibleIBIsEditField.Enable = false;
                app.MaxPlausibleIBIsEditField.Value = 10;
                
                app.AdaptiveThresholdingWindowLengthsEditField.Enable = false;
                app.AdaptiveThresholdingWindowLengthsEditField.Value = 60;
                
                app.AdaptiveThresholdingWindowShiftsEditField.Enable = false;
                app.AdaptiveThresholdingWindowShiftsEditField.Value=58;
            else
                app.sqi_js_threshEditField_2.Enable = true;
                app.sqi_jw_threshEditField_2.Enable = true;
                app.RemoveArrhythmCheckBox.Enable = true;
                app.UpperOutlierThresholdstddevEditField.Enable = true;
                app.LowerOutlierThresholdstddevEditField.Enable = true;
                app.AdaptiveThresholdingWindowLengthsEditField_2.Enable = true;
                app.AdaptiveThresholdingWindowShiftsEditField_2.Enable=true;
                app.vera_zamoscikButton.Enable =true;
                app.count_advButton.Enable = true;
                app.RQIThresholdEditField.Enable=true;
                app.RQIWindowShiftEditField.Enable =true;
                app.RQIWindowLengthsEditField.Enable = true;
                app.MinPlausibleIBIsEditField.Enable = true;
                app.MaxPlausibleIBIsEditField.Enable = true;
                app.AdaptiveThresholdingWindowLengthsEditField.Enable = true;
                app.AdaptiveThresholdingWindowShiftsEditField.Enable = true;
            end
        end

        % Value changed function: RemoveArrhythmCheckBox
        function RemoveArrhythmCheckBoxValueChanged(app, event)
            value = app.RemoveArrhythmCheckBox.Value;
            if value
                app.ArrhythmiaOutlierRemovalMADsEditField.Enable = true;
                app.UpperOutlierThresholdstddevEditField.Value = 3;
                app.LowerOutlierThresholdstddevEditField.Value = 3;
            else
                app.ArrhythmiaOutlierRemovalMADsEditField.Enable = false;
                app.UpperOutlierThresholdstddevEditField.Value = 4;
                app.LowerOutlierThresholdstddevEditField.Value = 4;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {509, 509};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {202, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ChooseDataFilesListBoxLabel = uilabel(app.LeftPanel);
            app.ChooseDataFilesListBoxLabel.Text = 'Choose Data File(s)';
            app.ChooseDataFilesListBox = uilistbox(app.LeftPanel);
            app.ChooseDataFilesListBox.Items = {};
            app.ChooseDataFilesListBox.Value = {};
            app.ChooseFilepathButton = uibutton(app.LeftPanel, 'push');
            app.ChooseFilepathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFilepath, true);
            app.ChooseFilepathButton.Text = 'Choose Filepath';
            app.DataSampleRateEditFieldLabel = uilabel(app.LeftPanel);
            app.DataSampleRateEditFieldLabel.Text = 'Data Sample Rate';
            app.DataSampleRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DataSampleRateEditField.Value = 2000;
            app.SamplingRateUnitsDropDownLabel = uilabel(app.LeftPanel);
            app.SamplingRateUnitsDropDownLabel.Text = 'Sampling Rate Units';
            app.SamplingRateUnitsDropDown = uidropdown(app.LeftPanel);
            app.SamplingRateUnitsDropDown.Items = {'Hz', 'kHz', 'ms', 'seconds'};
            app.SamplingRateUnitsDropDown.Value = 'Hz';
            app.ChosendatafilemustbeofLabel = uilabel(app.LeftPanel);
            app.ChosendatafilemustbeofLabel.Text = {'Chosen data file must contain a '; 'structure with fields ''ecg'' and ''rsp'' '; 'in order for this GUI to properly '; 'process the data. '};
            app.RightPanel = uipanel(app.GridLayout);
            app.RespirationPeakDetectionandQualityIndexingPanel = uipanel(app.RightPanel);
            app.RespirationPeakDetectionandQualityIndexingPanel.Title = 'Respiration Peak Detection and Quality Indexing';
            app.RespirationPatternVariabilityPanel = uipanel(app.RespirationPeakDetectionandQualityIndexingPanel);
            app.RespirationPatternVariabilityPanel.Title = 'Respiration Pattern Variability';
            app.AdaptiveThresholdingWindowLengthsEditField_2Label = uilabel(app.RespirationPatternVariabilityPanel);
            app.AdaptiveThresholdingWindowLengthsEditField_2Label.Text = {'Adaptive Thresholding '; 'Window Length (s)'};
            app.AdaptiveThresholdingWindowLengthsEditField_2 = uieditfield(app.RespirationPatternVariabilityPanel, 'numeric');
            app.AdaptiveThresholdingWindowLengthsEditField_2.Enable = 'off';
            app.AdaptiveThresholdingWindowLengthsEditField_2.Value = 300;
            app.AdaptiveThresholdingWindowShiftsEditField_2Label = uilabel(app.RespirationPatternVariabilityPanel);
            app.AdaptiveThresholdingWindowShiftsEditField_2Label.Text = {'Adaptive Thresholding '; 'Window Shift (s)'};
            app.AdaptiveThresholdingWindowShiftsEditField_2 = uieditfield(app.RespirationPatternVariabilityPanel, 'numeric');
            app.AdaptiveThresholdingWindowShiftsEditField_2.Enable = 'off';
            app.AdaptiveThresholdingWindowShiftsEditField_2.Value = 1;
            app.RespirationQualityAssessmentPanel = uipanel(app.RespirationPeakDetectionandQualityIndexingPanel);
            app.RespirationQualityAssessmentPanel.Title = 'Respiration Quality Assessment';
            app.RQIWindowLengthsEditFieldLabel = uilabel(app.RespirationQualityAssessmentPanel);
            app.RQIWindowLengthsEditFieldLabel.Text = 'RQI Window Length (s)';
            app.RQIWindowLengthsEditField = uieditfield(app.RespirationQualityAssessmentPanel, 'numeric');
            app.RQIWindowLengthsEditField.Enable = 'off';
            app.RQIWindowLengthsEditField.Value = 16;
            app.RQIThresholdEditFieldLabel = uilabel(app.RespirationQualityAssessmentPanel);
            app.RQIThresholdEditFieldLabel.Text = 'RQI Threshold';
            app.RQIThresholdEditField = uieditfield(app.RespirationQualityAssessmentPanel, 'numeric');
            app.RQIThresholdEditField.Enable = 'off';
            app.RQIThresholdEditField.Value = 0.45;
            app.RQIWindowShiftEditFieldLabel = uilabel(app.RespirationQualityAssessmentPanel);
            app.RQIWindowShiftEditFieldLabel.Text = 'RQI Window Shift';
            app.RQIWindowShiftEditField = uieditfield(app.RespirationQualityAssessmentPanel, 'numeric');
            app.RQIWindowShiftEditField.Enable = 'off';
            app.RQIWindowShiftEditField.Value = 16;
            app.ECGProcessingPanel = uipanel(app.RightPanel);
            app.ECGProcessingPanel.Title = 'ECG Processing';
            app.ECGRPeakDetectionPanel = uipanel(app.ECGProcessingPanel);
            app.ECGRPeakDetectionPanel.Title = 'ECG R Peak Detection';
            app.sqi_jw_threshEditField_2Label = uilabel(app.ECGRPeakDetectionPanel);
            app.sqi_jw_threshEditField_2Label.Text = 'sqi_jw_thresh';
            app.sqi_jw_threshEditField_2 = uieditfield(app.ECGRPeakDetectionPanel, 'numeric');
            app.sqi_jw_threshEditField_2.Enable = 'off';
            app.sqi_jw_threshEditField_2.Value = 0.7;
            app.sqi_js_threshEditField_2Label = uilabel(app.ECGRPeakDetectionPanel);
            app.sqi_js_threshEditField_2Label.Text = 'sqi_js_thresh';
            app.sqi_js_threshEditField_2 = uieditfield(app.ECGRPeakDetectionPanel, 'numeric');
            app.sqi_js_threshEditField_2.Enable = 'off';
            app.sqi_js_threshEditField_2.Value = 0.5;
            app.RemoveArrhythmCheckBox = uicheckbox(app.ECGRPeakDetectionPanel);
            app.RemoveArrhythmCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveArrhythmCheckBoxValueChanged, true);
            app.RemoveArrhythmCheckBox.Enable = 'off';
            app.RemoveArrhythmCheckBox.Text = 'Remove Arrhythm';
            app.ECGSignalQualityAssessmentPanel = uipanel(app.ECGProcessingPanel);
            app.ECGSignalQualityAssessmentPanel.Title = 'ECG Signal Quality Assessment';
            app.ArrhythmiaOutlierRemovalMADsLabel = uilabel(app.ECGSignalQualityAssessmentPanel);
            app.ArrhythmiaOutlierRemovalMADsLabel.Text = {'Arrhythmia Outlier Removal '; '(MADs)'};
            app.ArrhythmiaOutlierRemovalMADsEditField = uieditfield(app.ECGSignalQualityAssessmentPanel, 'numeric');
            app.ArrhythmiaOutlierRemovalMADsEditField.Enable = 'off';
            app.ArrhythmiaOutlierRemovalMADsEditField.Value = 2;
            app.UpperOutlierThresholdstddevEditFieldLabel = uilabel(app.ECGSignalQualityAssessmentPanel);
            app.UpperOutlierThresholdstddevEditFieldLabel.Text = 'Upper Outlier Threshold (std dev.)';
            app.UpperOutlierThresholdstddevEditField = uieditfield(app.ECGSignalQualityAssessmentPanel, 'numeric');
            app.UpperOutlierThresholdstddevEditField.Enable = 'off';
            app.UpperOutlierThresholdstddevEditField.Value = 4;
            app.LowerOutlierThresholdstddevEditFieldLabel = uilabel(app.ECGSignalQualityAssessmentPanel);
            app.LowerOutlierThresholdstddevEditFieldLabel.Text = 'Lower Outlier Threshold (std dev.)';
            app.LowerOutlierThresholdstddevEditField = uieditfield(app.ECGSignalQualityAssessmentPanel, 'numeric');
            app.LowerOutlierThresholdstddevEditField.Enable = 'off';
            app.LowerOutlierThresholdstddevEditField.Value = 4;
            app.RunProcessingButton = uibutton(app.RightPanel, 'push');
            app.RunProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunProcessingButton.Text = 'Run Processing';
            app.IwanttoeditprocessingparametersCheckBox = uicheckbox(app.RightPanel);
            app.IwanttoeditprocessingparametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @editOutlier, true);
            app.IwanttoeditprocessingparametersCheckBox.Text = 'I want to edit processing parameters';
            app.RespirationPeakDetectionPanel = uipanel(app.RightPanel);
            app.RespirationPeakDetectionPanel.Title = 'Respiration Peak Detection';
            app.MethodButtonGroup = uibuttongroup(app.RespirationPeakDetectionPanel);
            app.MethodButtonGroup.Title = 'Method';
            app.vera_zamoscikButton = uiradiobutton(app.MethodButtonGroup);
            app.vera_zamoscikButton.Enable = 'off';
            app.vera_zamoscikButton.Text = 'vera_zamoscik';
            app.vera_zamoscikButton.Value = true;
            app.count_advButton = uiradiobutton(app.MethodButtonGroup);
            app.count_advButton.Enable = 'off';
            app.count_advButton.Text = 'count_adv';
            app.MinPlausibleIBIsEditFieldLabel = uilabel(app.RespirationPeakDetectionPanel);
            app.MinPlausibleIBIsEditFieldLabel.Text = 'Min Plausible IBI (s)';
            app.MinPlausibleIBIsEditField = uieditfield(app.RespirationPeakDetectionPanel, 'numeric');
            app.MinPlausibleIBIsEditField.Enable = 'off';
            app.MinPlausibleIBIsEditField.Value = 1.4;
            app.MaxPlausibleIBIsEditFieldLabel = uilabel(app.RespirationPeakDetectionPanel);
            app.MaxPlausibleIBIsEditFieldLabel.Text = 'Max Plausible IBI (s)';
            app.MaxPlausibleIBIsEditField = uieditfield(app.RespirationPeakDetectionPanel, 'numeric');
            app.MaxPlausibleIBIsEditField.Enable = 'off';
            app.MaxPlausibleIBIsEditField.Value = 10;
            app.AdaptiveThresholdingWindowLengthsEditFieldLabel = uilabel(app.RespirationPeakDetectionPanel);
            app.AdaptiveThresholdingWindowLengthsEditFieldLabel.Text = {'Adaptive Thresholding '; 'Window Length (s)'};
            app.AdaptiveThresholdingWindowLengthsEditField = uieditfield(app.RespirationPeakDetectionPanel, 'numeric');
            app.AdaptiveThresholdingWindowLengthsEditField.Enable = 'off';
            app.AdaptiveThresholdingWindowLengthsEditField.Value = 60;
            app.AdaptiveThresholdingWindowShiftsEditFieldLabel = uilabel(app.RespirationPeakDetectionPanel);
            app.AdaptiveThresholdingWindowShiftsEditFieldLabel.Text = {'Adaptive Thresholding '; 'Window Shift (s)'};
            app.AdaptiveThresholdingWindowShiftsEditField = uieditfield(app.RespirationPeakDetectionPanel, 'numeric');
            app.AdaptiveThresholdingWindowShiftsEditField.Enable = 'off';
            app.AdaptiveThresholdingWindowShiftsEditField.Value = 58;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RespGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end